classdef AutmatedKineticModeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TIP20AutomatedKineticModelingUIFigure  matlab.ui.Figure
        btnFolderSelect              matlab.ui.control.Button
        InputFolderLabel             matlab.ui.control.Label
        txtPath                      matlab.ui.control.EditField
        chkMovCor                    matlab.ui.control.CheckBox
        MovementCorrectionLabel      matlab.ui.control.Label
        chkACOrig                    matlab.ui.control.CheckBox
        SetACOriginLabel             matlab.ui.control.Label
        chkDicom                     matlab.ui.control.CheckBox
        DICOMImportLabel             matlab.ui.control.Label
        chkSpatialNorm               matlab.ui.control.CheckBox
        SpatialNormalizationLabel    matlab.ui.control.Label
        chkKinMod                    matlab.ui.control.CheckBox
        KineticModelingLabel         matlab.ui.control.Label
        chkZTrans                    matlab.ui.control.CheckBox
        StatisticalComparisonLabel   matlab.ui.control.Label
        NormalDatabaseDropDownLabel  matlab.ui.control.Label
        NormalDatabaseDropDown       matlab.ui.control.DropDown
        ProtocolTextAreaLabel        matlab.ui.control.Label
        ProtocolTextArea             matlab.ui.control.TextArea
        ProcessDataButton            matlab.ui.control.Button
        FramestomodelDropDownLabel   matlab.ui.control.Label
        FramestomodelDropDown        matlab.ui.control.DropDown
        chkReportOutput              matlab.ui.control.CheckBox
        ReportOutputLabel            matlab.ui.control.Label
        chkSmooth                    matlab.ui.control.CheckBox
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.txtPath.Value = pwd;
        end

        % Button pushed function: btnFolderSelect
        function btnFolderSelectButtonPushed(app, event)
            try
                app.txtPath.Value = uigetdir;
                app.chkFolder.Value = true;
            end
            
        end

        % Value changed function: txtPath
        function txtPathValueChanged(app, event)
            value = app.txtPath.Value;
            app.chkFolder.Value = false;
        end

        % Value changed function: NormalDatabaseDropDown
        function NormalDatabaseDropDownValueChanged(app, event)
            value = app.NormalDatabaseDropDown.Value;
            %app.ProtocolTextArea.Value = strcat('Normal DB set: ', value);
            
            try
                app.ProtocolTextArea.Value = [{[datestr(datetime('now')) strcat(' Normal DB set: ', value)]}, app.ProtocolTextArea.Value(:)'];
            end
            
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            FolderNameSetOfNormals = app.NormalDatabaseDropDown.Value;
            numberOfFramesToModel = str2num(app.FramestomodelDropDown.Value(1:2));
            performAutoKinMod(app.txtPath.Value,app,'',FolderNameSetOfNormals, numberOfFramesToModel)
            
        end

        % Callback function
        function ButtonPushed(app, event)
            
            app.ProtocolTextArea.Value = [{[datestr(datetime('now')) ' processing started']}, app.ProtocolTextArea.Value(:)'];

        end

        % Value changed function: FramestomodelDropDown
        function FramestomodelDropDownValueChanged(app, event)
            value = app.FramestomodelDropDown.Value;
                        
            try
                app.ProtocolTextArea.Value = [{[datestr(datetime('now')) strcat(' Number of frames to model: ', value)]}, app.ProtocolTextArea.Value(:)'];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TIP20AutomatedKineticModelingUIFigure = uifigure;
            app.TIP20AutomatedKineticModelingUIFigure.Name = 'TIP 2.0 Automated Kinetic Modeling';
            app.btnFolderSelect = uibutton(app.TIP20AutomatedKineticModelingUIFigure, 'push');
            app.btnFolderSelect.ButtonPushedFcn = createCallbackFcn(app, @btnFolderSelectButtonPushed, true);
            app.btnFolderSelect.Text = 'Select';
            app.InputFolderLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.InputFolderLabel.Text = 'Input Folder';
            app.txtPath = uieditfield(app.TIP20AutomatedKineticModelingUIFigure, 'text');
            app.txtPath.ValueChangedFcn = createCallbackFcn(app, @txtPathValueChanged, true);
            app.chkMovCor = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkMovCor.Text = '';
            app.chkMovCor.Value = true;
            app.MovementCorrectionLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.MovementCorrectionLabel.Text = 'Movement Correction';
            app.chkACOrig = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkACOrig.Text = '';
            app.chkACOrig.Value = true;
            app.SetACOriginLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.SetACOriginLabel.Text = 'Set AC Origin';
            app.chkDicom = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkDicom.Text = '';
            app.chkDicom.Value = true;
            app.DICOMImportLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.DICOMImportLabel.Text = 'DICOM Import';
            app.chkSpatialNorm = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkSpatialNorm.Text = '';
            app.chkSpatialNorm.Value = true;
            app.SpatialNormalizationLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.SpatialNormalizationLabel.Text = 'Spatial Normalization';
            app.chkKinMod = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkKinMod.Text = '';
            app.chkKinMod.Value = true;
            app.KineticModelingLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.KineticModelingLabel.Text = 'Kinetic Modeling';
            app.chkZTrans = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkZTrans.Text = '';
            app.chkZTrans.Value = true;
            app.StatisticalComparisonLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.StatisticalComparisonLabel.Text = 'Statistical Comparison';
            app.NormalDatabaseDropDownLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.NormalDatabaseDropDownLabel.Text = 'Normal Database';
            app.NormalDatabaseDropDown = uidropdown(app.TIP20AutomatedKineticModelingUIFigure);
            app.NormalDatabaseDropDown.Items = {'PI2620_HC_Piramal', 'PI2620_HC_Piramal_0-60'};
            app.NormalDatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalDatabaseDropDownValueChanged, true);
            app.NormalDatabaseDropDown.Value = 'PI2620_HC_Piramal_0-60';
            app.ProtocolTextAreaLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.ProtocolTextAreaLabel.Text = 'Protocol';
            app.ProtocolTextArea = uitextarea(app.TIP20AutomatedKineticModelingUIFigure);
            app.ProtocolTextArea.Value = {'Startup!'};
            app.ProcessDataButton = uibutton(app.TIP20AutomatedKineticModelingUIFigure, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.FramestomodelDropDownLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.FramestomodelDropDownLabel.Text = 'Frames to model';
            app.FramestomodelDropDown = uidropdown(app.TIP20AutomatedKineticModelingUIFigure);
            app.FramestomodelDropDown.Items = {'23 (0-60 min)', '29 (0-90 min)'};
            app.FramestomodelDropDown.ValueChangedFcn = createCallbackFcn(app, @FramestomodelDropDownValueChanged, true);
            app.FramestomodelDropDown.Value = '23 (0-60 min)';
            app.chkReportOutput = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkReportOutput.Text = '';
            app.chkReportOutput.Value = true;
            app.ReportOutputLabel = uilabel(app.TIP20AutomatedKineticModelingUIFigure);
            app.ReportOutputLabel.Text = 'Report Output';
            app.chkSmooth = uicheckbox(app.TIP20AutomatedKineticModelingUIFigure);
            app.chkSmooth.Text = 'Smooth 4D file after normalization';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AutmatedKineticModeling

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TIP20AutomatedKineticModelingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TIP20AutomatedKineticModelingUIFigure)
        end
    end
end